classdef iceControlPanel < matlab.apps.AppBase

   % Properties that correspond to app components
   properties (Access = public)
      ice                  matlab.ui.Figure
      curve_axes           matlab.ui.control.UIAxes
      text1                matlab.ui.control.Label
      text2                matlab.ui.control.Label
      text3                matlab.ui.control.Label
      input_text           matlab.ui.control.Label
      output_text          matlab.ui.control.Label
      resetall_pushbutton  matlab.ui.control.Button
      blue_text            matlab.ui.control.Label
      green_text           matlab.ui.control.Label
      red_text             matlab.ui.control.Label
      text10               matlab.ui.control.Label
      text11               matlab.ui.control.Label
      mapbar_checkbox      matlab.ui.control.CheckBox
      mapimage_checkbox    matlab.ui.control.CheckBox
      uipanel1             matlab.ui.container.Panel
      smooth_checkbox      matlab.ui.control.CheckBox
      reset_pushbutton     matlab.ui.control.Button
      slope_checkbox       matlab.ui.control.CheckBox
      pdf_checkbox         matlab.ui.control.CheckBox
      cdf_checkbox         matlab.ui.control.CheckBox
      component_popup      matlab.ui.control.DropDown
   end

%   Written by Steve Eddins to accompany Digital Image Processing Using
%   MATLAB, 3rd edition, Gatesmark Press, 2020,
%   http://imageprocessingplace.com.
%
%   Copyright 2019 The MathWorks, Inc.
%   License: https://github.com/mathworks/matlab-color-tools/blob/master/license.txt

    properties (Access = private)
        updown = 'none'       % Mouse updown state
        plotbox = [0 0 1 1]   % Plot area parameters in pixels
        set1 = [0 0; 1 1]     % Curve 1 control points
        set2 = [0 0; 1 1]     % Curve 2 control points
        set3 = [0 0; 1 1]     % Curve 3 control points
        set4 = [0 0; 1 1]     % Curve 4 control points
        curve = 'set1'        % Structure name of selected curve
        cindex = 1            % Index of selected curve
        node = 0              % Index of selected control point
        below = 1             % Index of node below control point
        above = 2             % Index of node above control point
        smooth = [0; 0; 0; 0] % Curve smoothing states
        slope  = [0; 0; 0; 0] % Curve end slope control states
        cdf = [0; 0; 0; 0]    % Curve CDF states
        pdf = [0; 0; 0; 0]    % Curve PDF states
        output = []           % Output image handle
        df = []               % Input PDFs and CDFs
        colortype = 'rgb'     % Input image color space
        input = []            % Input image data
        imagemap = 1          % Image map enable
        barmap = 1            % Bar map enable
        graybar % Description
        colorbar % Description
        GrayBarImage % Description
        ColorBarImage % Description
    end
    
    methods (Access = private)
        function g = cmy2rgb(~, f)
            %   Convert CMY to RGB using IPT's imcomplement.
            
            g = imcomplement(f);
        end
        
        function [inplot, x, y] = cursor(app)
            %  Translate the mouse position to a coordinate with respect to
            %  the current plot area, check for the mouse in the area and if so
            %  save the location and write the coordinates below the plot.
            
%             set(h, 'Units', 'pixels');
%             p = get(h, 'CurrentPoint');
            p = app.ice.CurrentPoint;
            x = (p(1, 1) - app.plotbox(1)) / app.plotbox(3);
            y = (p(1, 2) - app.plotbox(2)) / app.plotbox(4);
            if x > 1.05 || x < -0.05 || y > 1.05 || y < -0.05
               inplot = 0;
            else
               x = min(x, 1);      x = max(x, 0);
               y = min(y, 1);      y = max(y, 0);
               % nodes = getfield(handles, app.curve);
               nodes = app.(app.curve);
               x = round(256 * x) / 256;
               inplot = 1;
               % set(app.input_text, 'String', num2str(x, 3));
               % set(app.output_text, 'String', num2str(y, 3));
               set(app.input_text, 'Text', num2str(x, 3));
               set(app.output_text, 'Text', num2str(y, 3));
            end
            % set(h, 'Units', 'normalized');
        end
        
        function graph(app)
            %  Interpolate and plot mapping functions and optional reference
            %  PDF(s) or CDF(s).
            
            nodes = eval(['app.' app.curve]); %TODO refactor this
            c = app.cindex;     dfx = 0:1/255:1;
            colors = ['k' 'r' 'g' 'b'];
            
            % For piecewise linear interpolation, plot a map, map + PDF/CDF, or
            % map + 3 PDFs/CDFs.
            if ~app.smooth(app.cindex)
               if (~app.pdf(c) && ~app.cdf(c)) || ...
                     (size(app.df, 2) == 0)
                  plot(nodes(:, 1), nodes(:, 2), 'b-', ...
                       nodes(:, 1),  nodes(:, 2), 'ko', ...
                       'Parent', app.curve_axes);
               elseif c > 1
                  i = 2 * c - 2 - app.pdf(c);
                  plot(dfx, app.df(i, :), [colors(c) '-'], ...
                       nodes(:, 1), nodes(:, 2), 'k-', ...
                       nodes(:, 1), nodes(:, 2), 'ko', ...
                       'Parent', app.curve_axes);
               elseif c == 1
                  i = app.cdf(c);
                  plot(dfx, app.df(i + 1, :), 'r-', ...
                       dfx, app.df(i + 3, :), 'g-',  ...
                       dfx, app.df(i + 5, :), 'b-', ...
                       nodes(:, 1), nodes(:, 2), 'k-',  ...
                       nodes(:, 1), nodes(:, 2), 'ko', ...
                       'Parent', app.curve_axes);
               end
            
            % Do the same for smooth (cubic spline) interpolations.
            else
               x = 0:0.01:1;
               if ~app.slope(app.cindex)
                  y = spline(nodes(:, 1), nodes(:, 2), x);
               else
                  y = spline(nodes(:, 1), [0; nodes(:, 2); 0], x);
               end
               i = find(y > 1);       y(i) = 1;
               i = find(y < 0);       y(i) = 0;
            
               if (~app.pdf(c) && ~app.cdf(c)) || ...
                     (size(app.df, 2) == 0)
                  plot(nodes(:, 1), nodes(:, 2), 'ko',  x, y, 'b-', ...
                       'Parent', app.curve_axes);
               elseif c > 1
                  i = 2 * c - 2 - app.pdf(c);
                  plot(dfx, app.df(i, :), [colors(c) '-'], ...
                       nodes(:, 1), nodes(:, 2), 'ko', x, y, 'k-', ...
                       'Parent', app.curve_axes);
               elseif c == 1
                  i = app.cdf(c);
                  plot(dfx, app.df(i + 1, :), 'r-', ...
                       dfx, app.df(i + 3, :), 'g-', ...
                       dfx, app.df(i + 5, :), 'b-', ...
                       nodes(:, 1), nodes(:, 2), 'ko', x, y, 'k-', ...
                       'Parent', app.curve_axes);
               end
            end
            
            % Put legend if more than two curves are shown.
            s = app.colortype;
            if strcmp(s, 'ntsc')
                s = 'yiq';
            end
            if (c == 1) && (app.pdf(c) || app.cdf(c))
               s1 = ['-- ' upper(s(1))];
               if length(s) == 3
                  s2 = ['-- ' upper(s(2))];       s3 = ['-- ' upper(s(3))];
               else
                  s2 = ['-- ' upper(s(2)) s(3)];  s3 = ['-- ' upper(s(4)) s(5)];
               end
            else
               s1 = '';    s2 = '';    s3 = '';
            end
            app.red_text.Text = s1;
            app.green_text.Text = s2;
            app.blue_text.Text = s3;
%             set(app.red_text, 'String', s1);
%             set(app.green_text, 'String', s2);
%             set(app.blue_text, 'String', s3);
        end
        
        function t = lut(app, nodes, smooth, slope)
            %  Create a 256 element mapping function from a set of control
            %  points. The output values are integers in the interval [0, 255].
            %  Use piecewise linear or cubic spline with or without zero end
            %  slope interpolation.
            
            t = 255 * nodes;    i = 0:255;
            if ~smooth
               t = [t; 256 256];   t = interp1q(t(:, 1), t(:, 2), i');
            else
               if ~slope
                  t = spline(t(:, 1), t(:, 2), i);
               else
                  t = spline(t(:, 1), [0; t(:, 2); 0], i);
               end
            end
            t = round(t);       t = max(0, t);          t = min(255, t);
        end
        
        function y = render(app)
            %  Map the input image and bar components and convert them to RGB
            %  (if needed) and display.
            
            set(app.ice, 'Interruptible', 'off');
            % set(app.ice, 'Pointer', 'watch');
            ygb = app.graybar;      ycb = app.colorbar;
            yi = app.input;         mapon = app.barmap;
            imageon = app.imagemap & size(app.input, 1);
            
            for i = 2:4
               % nodes = getfield(handles, ['set' num2str(i)]);
               nodes = app.(['set' num2str(i)]);
               % nodes = eval(['app.set' num2str(i)]);
               t = lut(app,nodes, app.smooth(i), app.slope(i));
               if imageon
                  yi(:, :, i - 1) = t(yi(:, :, i - 1) + 1);
               end
               if mapon
                  ygb(:, :, i - 1) = t(ygb(:, :, i - 1) + 1);
                  ycb(:, :, i - 1) = t(ycb(:, :, i - 1) + 1);
               end
            end
            t = lut(app, app.set1, app.smooth(1), app.slope(1));
            if imageon
               yi = t(yi + 1);
            end
            if mapon
               ygb = t(ygb + 1);     ycb = t(ycb + 1);
            end
            
%             if ~strcmp(app.colortype, 'rgb')
%                if size(app.input, 1)
%                   yi = yi / 255;
%                   yi = eval([app.colortype '2rgb(app,yi)']);
%                   yi = uint8(255 * yi);
%                end
%                ygb = ygb / 255;        ycb = ycb / 255;
%                ygb = eval([app.colortype '2rgb(app,ygb)']);
%                ycb = eval([app.colortype '2rgb(app,ycb)']);
%                ygb = uint8(255 * ygb);     ycb = uint8(255 * ycb);
            if strcmp(app.colortype, 'cmy')
               if size(app.input, 1)
                  yi = yi / 255;
                  % yi = eval([app.colortype '2rgb(app,yi)']);
                  yi = cmy2rgb(app,yi);
                  yi = uint8(255 * yi);
               end
               ygb = ygb / 255;        ycb = ycb / 255;
               % ygb = eval([app.colortype '2rgb(app,ygb)']);
               ygb = cmy2rgb(app,ygb);
               % ycb = eval([app.colortype '2rgb(app,ycb)']);
               ycb = cmy2rgb(app,ycb);
               ygb = uint8(255 * ygb);     ycb = uint8(255 * ycb);  
            elseif strcmp(app.colortype,'hsi')
               if size(app.input, 1)
                  yi = yi / 255;
                  % yi = eval([app.colortype '2rgb(app,yi)']);
                  yi = hsi2rgb(yi);
                  yi = uint8(255 * yi);
               end
               ygb = ygb / 255;        ycb = ycb / 255;
               % ygb = eval([app.colortype '2rgb(app,ygb)']);
               ygb = hsi2rgb(ygb);
               % ycb = eval([app.colortype '2rgb(app,ycb)']);
               ycb = hsi2rgb(ycb);
               ygb = uint8(255 * ygb);     ycb = uint8(255 * ycb); 
            else
               yi = uint8(yi);   ygb = uint8(ygb);     ycb = uint8(ycb);
            end
            
            if size(app.input, 1)
               % figure(app.output);
               if isempty(app.output.CurrentAxes)
                   app.output.CurrentAxes = axes;
               end
               imshow(yi,'Parent',app.output.CurrentAxes);
            end
            ygb = repmat(ygb, [32 1 1]);    ycb = repmat(ycb, [32 1 1]);
            if ~verLessThan('matlab','9.6')
               app.GrayBarImage.ImageSource = ygb;
               app.ColorBarImage.ImageSource = ycb;
            end
%             % axes(app.gray_axes);        imshow(ygb);
%             % axes(app.color_axes);       imshow(ycb);
%             imshow(ygb,'InitialMagnification','fit','Parent',app.gray_axes)
%             imshow(ycb,'InitialMagnification','fit','Parent',app.color_axes)
            
            figure(app.ice);
            set(app.ice, 'Pointer', 'arrow');
            set(app.ice, 'Interruptible', 'on');
        end
        
        function g = rgb2cmy(app, f)
            %   Convert RGB to CMY using IPT's imcomplement.
            
            g = imcomplement(f);
        end
        
        function out = spreadout(app, in)
            %  Make all x values unique.
            
            % Scan forward for non-unique x's and bump the higher indexed x--
            % but don't exceed 1. Scan the entire range.
            nudge = 1 / 256;
            for i = 2:size(in, 1) - 1
               if in(i, 1) <= in(i - 1, 1)
                  in(i, 1) = min(in(i - 1, 1) + nudge, 1);
               end
            end
            
            % Scan in reverse for non-unique x's and decrease the lower indexed
            % x -- but don't go below 0. Stop on the first non-unique pair.
            if in(end, 1) == in(end - 1, 1)
               for i = size(in, 1):-1:2
                  if in(i, 1) <= in(i - 1, 1)
                     in(i - 1, 1) = max(in(i, 1) - nudge, 0);
                  else
                     break;
                  end
               end
            end
            
            % If the first two x's are now the same, init the curve.
            if in(1, 1) == in(2, 1)
               in = [0 0; 1 1];
            end
            out = in;
        end
        
    end
    

   % Callbacks that handle component events
   methods (Access = private)

      % Code that executes after component creation
      function ice_OpeningFcn(app, f, colorspace)
            % Temporary argument parsing (to get an image loaded)
            if nargin > 1
                app.input = im2double(f);
            else
                app.input = im2double(imread('peppers.png'));
            end
            
            if nargin < 3
                app.colortype = 'rgb';
            else
                app.colortype = colorspace;
            end
            
            set(app.curve_axes,'DefaultLineLineWidth',1.5);
            
            if strcmp(app.colortype,'cmy')
                app.component_popup.Items = {'CMY','Cyan','Magenta','Yellow'};
                app.component_popup.Value = 'CMY';
            elseif strcmp(app.colortype,'hsi')
                app.component_popup.Items = {'HSI','Hue','Saturation','Intensity'};
                app.component_popup.Value = 'HSI';                
            end
            
%             % Process Property Name/Property Value input argument pairs.
%             wait = 'on';
%             if (nargin > 3)
%                for i = 1:2:(nargin - 3)
%                   if nargin - 3 == i
%                      break;
%                   end
%                   switch lower(varargin{i})
%                   case 'image'
%                      if ndims(varargin{i + 1}) == 3
%                         app.input = varargin{i + 1};
%                      elseif ndims(varargin{i + 1}) == 2
%                         app.input = cat(3, varargin{i + 1}, ...
%                                             varargin{i + 1}, varargin{i + 1});
%                      end
%                      app.input = double(app.input);
%                      inputmax = max(app.input(:));
%                      if inputmax > 255
%                         app.input = app.input / 65535;
%                      elseif inputmax > 1
%                         app.input = app.input / 255;
%                      end
%             
%                   case 'space'
%                      app.colortype = lower(varargin{i + 1});
%                      switch app.colortype
%                      case 'cmy'
%                         list = {'CMY' 'Cyan' 'Magenta' 'Yellow'};
%                      case {'ntsc', 'yiq'}
%                         list = {'YIQ' 'Luminance' 'Hue' 'Saturation'};
%                         app.colortype = 'ntsc';
%                      case 'ycbcr'
%                         list = {'YCbCr' 'Luminance' 'Blue' ...
%                                 'Difference'  'Red Difference'};
%                      case 'hsv'
%                         list = {'HSV' 'Hue' 'Saturation' 'Value'};
%                      case 'hsi'
%                         list = {'HSI' 'Hue' 'Saturation' 'Intensity'};
%                      otherwise
%                         list = {'RGB' 'Red' 'Green' 'Blue'};
%                         app.colortype = 'rgb';
%                      end
%                      set(app.component_popup, 'String', list);
%             
%                   case 'wait'
%                      wait = lower(varargin{i + 1});
%                   end
%                end
%             end
%             
         
%         app.gray_axes = axes('Units','pixels','Position',[315 142 128 32],...
%             'Visible','off','Parent',app.ice);
%         
%         app.color_axes = axes('Units','pixels','Position',[315 84 128 32],...
%             'Visible','off','Parent',app.ice);

        % Create pseudo- and full-color mapping bars (grays and hues). Store
        % a color space converted 1x128x3 line of each bar for mapping.
        xi = 0:1/127:1;     x = 0:1/6:1;     x = x';
        y = [1 1 0 0 0 1 1; 0 1 1 1 0 0 0; 0 0 0 1 1 1 0]';
        gb = repmat(xi, [1 1 3]);       cb = interp1q(x, y, xi');
        cb = reshape(cb, [1 128 3]);
%         if ~strcmp(app.colortype, 'rgb')
%            gb = eval(['rgb2' app.colortype '(gb)']);
%            cb = eval(['rgb2' app.colortype '(cb)']);
%         end
        if strcmp(app.colortype,'cmy')
            gb = rgb2cmy(app,gb);
            cb = rgb2cmy(app,cb);
        elseif strcmp(app.colortype,'hsi')
            gb = rgb2hsi(gb);
            cb = rgb2hsi(cb);
        end
        
        gb = round(255 * gb);     gb = max(0, gb);      gb = min(255, gb);
        cb = round(255 * cb);     cb = max(0, cb);      cb = min(255, cb);
        app.graybar = gb;     app.colorbar = cb;
                    
        % Do color space transforms, clamp to [0, 255], compute histograms
        % and cumulative distribution functions, and create output figure.
        if size(app.input, 1)
%            if ~strcmp(app.colortype, 'rgb')
%               app.input = eval(['rgb2' app.colortype ...
%                                    '(app.input)']);
%            end
           if strcmp(app.colortype,'cmy')
               app.input = rgb2cmy(app,app.input);
           elseif strcmp(app.colortype,'hsi')
               app.input = rgb2hsi(app.input);
           end
           app.input = round(255 * app.input);
           app.input = max(0, app.input);
           app.input = min(255, app.input);
           for i = 1:3
              color = app.input(:, :, i);
              df = hist(color(:), 0:255);
              app.df = [app.df; df / max(df(:))];
              df = df / sum(df(:));   df = cumsum(df);
              app.df = [app.df; df];
           end
           app.output = figure;
        end
            
        % Compute ICE's screen position and display image/graph.
        set(0, 'Units', 'pixels');      ssz = get(0, 'Screensize');
        set(app.ice, 'Units', 'pixels');
        uisz = get(app.ice, 'Position');
        if size(app.input, 1)
           fsz = get(app.output, 'Position');
           bc = (fsz(4) - uisz(4)) / 3;
           if bc > 0
              bc = bc + fsz(2);
           else
              bc = fsz(2) + fsz(4) - uisz(4) - 10;
           end
           lc = fsz(1) + (size(app.input, 2) / 4) + (3 * fsz(3) / 4);
           lc = min(lc, ssz(3) - uisz(3) - 10);
           set(app.ice, 'Position', [lc bc 463 391]);
        else
           bc = round((ssz(4) - uisz(4)) / 2) - 10;
           lc = round((ssz(3) - uisz(3)) / 2) - 10;
           set(app.ice, 'Position', [lc bc uisz(3) uisz(4)]);
        end
        
        if verLessThan('matlab','9.6')
            warning("Interactive Color Editor requires " + ...
                "MATLAB R2019a to be fully functional. " + ...
                "Pseudo-color bar and full-color bar will not be displayed.")
        else
            app.GrayBarImage = uiimage('Parent',app.ice,'Position',[315 143 128 31]);
            app.ColorBarImage = uiimage('Parent',app.ice,'Position',[315 79 128 31]);           
        end
        
        app.curve_axes.XLim = [0 1];
        app.curve_axes.YLim = [0 1];
        app.curve_axes.Toolbar.Visible = 'off';
        
        % set(app.ice, 'Units', 'normalized');
        graph(app);     render(app);
%             
%             % Update handles and make ICE wait before exit if required.
%             guidata(hObject, handles);
%             if strcmpi(wait, 'on')
%                uiwait(app.ice);
%             end
      end

      % Value changed function: cdf_checkbox
      function cdf_checkbox_Callback(app, event)
            %  Accept CDF (cumulative distribution function) display parameter
            %  for selected color component and redraw mapping function if
            %  smoothing is on. If set, clear CDF display.
            
            if event.Value == 1
               app.cdf(app.cindex) = 1;
               set(app.pdf_checkbox, 'Value', 0);
               app.pdf(app.cindex) = 0;
            else
               app.cdf(app.cindex) = 0;
            end
            % guidata(hObject, handles);      
            graph(app);
      end

      % Value changed function: component_popup
      function component_popup_Callback(app, event)
            %  Accept color component selection, update component specific
            %  parameters on GUI, and draw the selected mapping function.
            
            % c = get(hObject, 'Value');
            c = find(strcmp(event.Value,app.component_popup.Items));
            app.cindex = c;
            app.curve = strcat('set', num2str(c));
            % guidata(hObject, handles);
            set(app.smooth_checkbox, 'Value', app.smooth(c));
            set(app.slope_checkbox, 'Value', app.slope(c));
            set(app.pdf_checkbox, 'Value', app.pdf(c));
            set(app.cdf_checkbox, 'Value', app.cdf(c));
            graph(app);
      end

      % Window button down function: ice
      function ice_WindowButtonDownFcn(app, event)
            %  Start mapping function control point editing. Do move, add, or
            %  delete for left, middle, and right button mouse clicks ('normal',
            %  'extend', and 'alt' cases) over plot area.
            
            % set(app.curve_axes, 'Units', 'pixels');
            app.plotbox = get(app.curve_axes, 'Position');
            % set(app.curve_axes, 'Units', 'normalized');
            [inplot, x, y] = cursor(app);
            if inplot
               nodes = app.(app.curve);
               % nodes = getfield(handles, app.curve);
               i = find(x >= nodes(:, 1));      below = max(i);
               above = min(below + 1, size(nodes, 1));
               if (x - nodes(below, 1)) > (nodes(above, 1) - x)
                  node = above;
               else
                  node = below;
               end
               deletednode = 0;
            
               % switch get(hObject, 'SelectionType')
               switch app.ice.SelectionType
               case 'normal'
                  if node == above
                     above = min(above + 1, size(nodes, 1));
                  elseif node == below
                     below = max(below - 1, 1);
                  end
                  if node == size(nodes, 1)
                     below = above;
                  elseif node == 1
                     above = below;
                  end
                  if x > nodes(above, 1)
                     x = nodes(above, 1);
                  elseif x < nodes(below, 1)
                     x = nodes(below, 1);
                  end
                  app.node = node;     app.updown = 'down';
                  app.below = below;   app.above = above;
                  nodes(node, :) = [x y];
               case 'extend'
                  if ~any(nodes(:, 1) == x)
                     nodes = [nodes(1:below, :); [x y]; nodes(above:end, :)];
                     app.node = above;   app.updown = 'down';
                     app.below = below;  app.above = above + 1;
                  end
               case 'alt'
                  if (node ~= 1) && (node ~= size(nodes, 1))
                     nodes(node, :) = [];   deletednode = 1;
                  end
                  app.node = 0;
                  set(app.input_text, 'Text', '');
                  set(app.output_text, 'Text', '');
               end
            
               % handles = setfield(handles, app.curve, nodes);
               app.(app.curve) = nodes;
               % guidata(hObject, handles);
               graph(app);
               if deletednode
                  render(app);
               end
            end
      end

      % Window button motion function: ice
      function ice_WindowButtonMotionFcn(app, event)
            %  Do nothing unless a mouse 'down' event has occurred. If it has,
            %  modify control point and make new mapping function.
            
            if ~strcmpi(app.updown, 'down')
               return;
            end
            [inplot, x, y] = cursor(app);
            if inplot
               % nodes = getfield(handles, app.curve);
               nodes = app.(app.curve);
               nudge = app.smooth(app.cindex) / 256;
               if (app.node ~= 1) && (app.node ~= size(nodes, 1))
                  if x >= nodes(app.above, 1)
                     x = nodes(app.above, 1) - nudge;
                  elseif x <= nodes(app.below, 1)
                     x = nodes(app.below, 1) + nudge;
                  end
               else
                  if x > nodes(app.above, 1)
                     x = nodes(app.above, 1);
                  elseif x < nodes(app.below, 1)
                     x = nodes(app.below, 1);
                  end
               end
               nodes(app.node, :) = [x y];
               % handles = setfield(handles, app.curve, nodes);
               app.(app.curve) = nodes;
               % guidata(hObject, handles);
               graph(app);
            end
      end

      % Window button up function: ice
      function ice_WindowButtonUpFcn(app, event)
            %  Terminate ongoing control point move or add operation. Clear
            %  coordinate text below plot and update display.
            
            update = strcmpi(app.updown, 'down');
            app.updown = 'up';      app.node = 0;
            % guidata(hObject, handles);
            if update
               set(app.input_text, 'Text', '');
               set(app.output_text, 'Text', '');
               render(app);
            end
      end

      % Value changed function: mapbar_checkbox
      function mapbar_checkbox_Callback(app, event)
            %  Accept changes to bar map enable state and redraw bars.
            
%             app.barmap = get(hObject, 'Value');
%             guidata(hObject, handles);  
            app.barmap = event.Value;
            render(app);
      end

      % Value changed function: mapimage_checkbox
      function mapimage_checkbox_Callback(app, event)
            %  Accept changes to the image map state and redraw image.
            
%             app.imagemap = get(hObject, 'Value');
%             guidata(hObject, handles);      
            app.imagemap = event.Value;
            render(app);
      end

      % Value changed function: pdf_checkbox
      function pdf_checkbox_Callback(app, event)
            %  Accept PDF (probability density function or histogram) display
            %  parameter for currently selected color component and redraw
            %  mapping function if smoothing is on. If set, clear CDF display.
            
            if event.Value == 1
               app.pdf(app.cindex) = 1;
               set(app.cdf_checkbox, 'Value', 0);
               app.cdf(app.cindex) = 0;
            else
               app.pdf(app.cindex) = 0;
            end
            % guidata(hObject, handles);      
            graph(app);
      end

      % Button pushed function: reset_pushbutton
      function reset_pushbutton_Callback(app, event)
            %  Init all display parameters for currently selected color
            %  component, make map 1:1, and redraw it.
            
            % handles = setfield(handles, app.curve, [0 0; 1 1]);
            app.(app.curve) = [0 0; 1 1];
            c = app.cindex;
            app.smooth(c) = 0;  set(app.smooth_checkbox, 'Value', 0);
            app.slope(c) = 0;   set(app.slope_checkbox, 'Value', 0);
            app.pdf(c) = 0;     set(app.pdf_checkbox, 'Value', 0);
            app.cdf(c) = 0;     set(app.cdf_checkbox, 'Value', 0);
            % guidata(hObject, handles);
            % set(app.ice, 'Pointer', 'watch');
            graph(app);      
            render(app);
            % set(app.ice, 'Pointer', 'arrow');
      end

      % Button pushed function: resetall_pushbutton
      function resetall_pushbutton_Callback(app, event)
            %  Init display parameters for color components, make all maps 1:1,
            %  and redraw display.
            
            for c = 1:4
               app.smooth(c) = 0;      app.slope(c) = 0;
               app.pdf(c) = 0;         app.cdf(c) = 0;
               app.(['set' num2str(c)]) = [0 0; 1 1];
               % handles = setfield(handles, ['set' num2str(c)], [0 0; 1 1]);
            end
            set(app.smooth_checkbox, 'Value', 0);
            set(app.slope_checkbox, 'Value', 0);
            set(app.pdf_checkbox, 'Value', 0);
            set(app.cdf_checkbox, 'Value', 0);
%             guidata(hObject, handles);
%             set(app.ice, 'Pointer', 'watch');
            graph(app);      
            render(app);
%             set(app.ice, 'Pointer', 'arrow');
      end

      % Value changed function: slope_checkbox
      function slope_checkbox_Callback(app, event)
            %  Accept slope clamp for currently selected color component and
            %  draw function if smoothing is on.
            
            if event.Value == 1
               app.slope(app.cindex) = 1;
            else
               app.slope(app.cindex) = 0;
            end
            % guidata(hObject, handles);
            if app.smooth(app.cindex)
               % set(app.ice, 'Pointer', 'watch');
               graph(app);      
               render(app);
               % set(app.ice, 'Pointer', 'arrow');
            end
      end

      % Value changed function: smooth_checkbox
      function smooth_checkbox_Callback(app, event)
            %  Accept smoothing parameter for currently selected color
            %  component and redraw mapping function.
            
            if event.Value == 1
               app.smooth(app.cindex) = 1;
               nodes = app.(app.curve);
               nodes = spreadout(app,nodes);
               app.(app.curve) = nodes;
            else
               app.smooth(app.cindex) = 0;
            end
            % guidata(hObject, handles);
            % set(app.ice, 'Pointer', 'watch');
            graph(app);      
            render(app);
            % set(app.ice, 'Pointer', 'arrow');
      end
   end

   % Component initialization
   methods (Access = private)

      % Create UIFigure and components
      function createComponents(app)
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         app.ice = uifigure('Visible', 'off');
         app.ice.Name = 'ICE - Interactive Color Editor';
         app.ice.WindowButtonDownFcn = createCallbackFcn(app, @ice_WindowButtonDownFcn, true);
         app.ice.WindowButtonUpFcn = createCallbackFcn(app, @ice_WindowButtonUpFcn, true);
         app.ice.WindowButtonMotionFcn = createCallbackFcn(app, @ice_WindowButtonMotionFcn, true);
         app.curve_axes = uiaxes(app.ice);
         app.curve_axes.XLim = [0 1];
         app.curve_axes.YLim = [0 1];
         app.curve_axes.XTick = [0 0.25 0.5 0.75 1];
         app.curve_axes.YTick = [0 0.25 0.5 0.75 1];
         app.text1 = uilabel(app.ice);
         app.text1.Text = 'Component:';
         app.text2 = uilabel(app.ice);
         app.text2.Text = 'Input:';
         app.text3 = uilabel(app.ice);
         app.text3.Text = 'Output:';
         app.input_text = uilabel(app.ice);
         app.input_text.Text = '';
         app.output_text = uilabel(app.ice);
         app.output_text.Text = '';
         app.resetall_pushbutton = uibutton(app.ice, 'push');
         app.resetall_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @resetall_pushbutton_Callback, true);
         app.resetall_pushbutton.Text = 'Reset All';
         app.blue_text = uilabel(app.ice);
         app.blue_text.Text = '';
         app.green_text = uilabel(app.ice);
         app.green_text.Text = '';
         app.red_text = uilabel(app.ice);
         app.red_text.Text = '';
         app.text10 = uilabel(app.ice);
         app.text10.Text = 'Pseudo-color Bar';
         app.text11 = uilabel(app.ice);
         app.text11.Text = 'Full-color Bar';
         app.mapbar_checkbox = uicheckbox(app.ice);
         app.mapbar_checkbox.ValueChangedFcn = createCallbackFcn(app, @mapbar_checkbox_Callback, true);
         app.mapbar_checkbox.Text = 'Map Bars';
         app.mapbar_checkbox.Value = true;
         app.mapimage_checkbox = uicheckbox(app.ice);
         app.mapimage_checkbox.ValueChangedFcn = createCallbackFcn(app, @mapimage_checkbox_Callback, true);
         app.mapimage_checkbox.Text = 'Map Image';
         app.mapimage_checkbox.Value = true;
         app.uipanel1 = uipanel(app.ice);
         app.uipanel1.Title = 'Curve';
         app.smooth_checkbox = uicheckbox(app.uipanel1);
         app.smooth_checkbox.ValueChangedFcn = createCallbackFcn(app, @smooth_checkbox_Callback, true);
         app.smooth_checkbox.Text = 'Smooth';
         app.reset_pushbutton = uibutton(app.uipanel1, 'push');
         app.reset_pushbutton.ButtonPushedFcn = createCallbackFcn(app, @reset_pushbutton_Callback, true);
         app.reset_pushbutton.Text = 'Reset';
         app.slope_checkbox = uicheckbox(app.uipanel1);
         app.slope_checkbox.ValueChangedFcn = createCallbackFcn(app, @slope_checkbox_Callback, true);
         app.slope_checkbox.Text = 'Clamp Ends';
         app.pdf_checkbox = uicheckbox(app.uipanel1);
         app.pdf_checkbox.ValueChangedFcn = createCallbackFcn(app, @pdf_checkbox_Callback, true);
         app.pdf_checkbox.Text = 'Show PDF';
         app.cdf_checkbox = uicheckbox(app.uipanel1);
         app.cdf_checkbox.ValueChangedFcn = createCallbackFcn(app, @cdf_checkbox_Callback, true);
         app.cdf_checkbox.Text = 'Show CDF';
         app.component_popup = uidropdown(app.ice);
         app.component_popup.Items = {'RGB', 'Red', 'Green', 'Blue'};
         app.component_popup.ValueChangedFcn = createCallbackFcn(app, @component_popup_Callback, true);
         app.component_popup.Value = 'RGB';
         % Show the figure after all components are created
         app.ice.Visible = 'on';
      end
   end

   % App creation and deletion
   methods (Access = public)

      % Construct app
      function app = iceControlPanel(varargin)

         % Create UIFigure and components
         createComponents(app)

         % Register the app with App Designer
         registerApp(app, app.ice)

         % Execute the startup function
         runStartupFcn(app, @(app)ice_OpeningFcn(app, varargin{:}))

         if nargout == 0
            clear app
         end
      end

      % Code that executes before app deletion
      function delete(app)

         % Delete UIFigure when app is deleted
         delete(app.ice)
      end
   end
end